classdef Covid19StatisticsProgram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Toolbar                     matlab.ui.container.Toolbar
        SetUIFigureColorTool        matlab.ui.container.toolbar.PushTool
        SendEmailTool               matlab.ui.container.toolbar.PushTool
        OpenGitHubRepoTool          matlab.ui.container.toolbar.PushTool
        Image                       matlab.ui.control.Image
        Hyperlink                   matlab.ui.control.Hyperlink
        OptionsButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                 matlab.ui.control.RadioButton
        CumulativeButton            matlab.ui.control.RadioButton
        DatatoplotButtonGroup       matlab.ui.container.ButtonGroup
        BothButton                  matlab.ui.control.RadioButton
        DeathsButton                matlab.ui.control.RadioButton
        CasesButton                 matlab.ui.control.RadioButton
        AveragednumberofdaysSlider  matlab.ui.control.Slider
        AveragednumberofdaysSliderLabel  matlab.ui.control.Label
        StateorRegionListBox        matlab.ui.control.ListBox
        CountryListBox              matlab.ui.control.ListBox
        StateorRegionLabel          matlab.ui.control.Label
        CountryLabel                matlab.ui.control.Label
        ToDatePicker                matlab.ui.control.DatePicker
        ToDatePickerLabel           matlab.ui.control.Label
        FromDatePicker              matlab.ui.control.DatePicker
        FromDatePickerLabel         matlab.ui.control.Label
        DefaultButton               matlab.ui.control.Button
        ClearButton                 matlab.ui.control.Button
        EditField                   matlab.ui.control.EditField
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties
        GlobalData
        Dates
        StartDate                 % Start date for plotting
        EndDate                   % End date for plotting
        ListOfCountries
        ListOfCountryNames
        NumberOfCountries
        NumberOfStates
        StateMap
        GlobalCumulativeCases
        GlobalCumulativeDeaths
        GlobalDailyCases
        GlobalDailyDeaths
        AverageCumulativeCases    % moving average of cumulative cases
        AverageCumulativeDeaths   % moving average of cumulative deaths
        AverageDailyCases         % moving average of daily cases
        AverageDailyDeaths        % moving average of daily deaths
        TimePoints
        AverageOfDay
        CountryorStateorRegionToFind
        CountrySelected
        StateSelected
        ShowCases
        ShowDeaths
        ShowBoth
        ShowCumulative
        ShowDaily
    end
    
    methods (Access = private)
        function ComputeAverages(app)
            if (app.AverageOfDay > 1)
                if isequal(app.CountrySelected, 'Global')
                    app.AverageDailyCases = movmean(app.GlobalDailyCases, app.AverageOfDay);
                    app.AverageDailyDeaths = movmean(app.GlobalDailyDeaths, app.AverageOfDay);
                    app.AverageCumulativeCases = movmean(app.GlobalCumulativeCases, app.AverageOfDay);
                    app.AverageCumulativeDeaths = movmean(app.GlobalCumulativeDeaths, app.AverageOfDay);
                else
                    IndexOfCurrentCountryExcludingGlobal = findobj([app.ListOfCountries{:}], 'Name', app.CountrySelected).IndexOfCountryExcludingGlobal;
                    if isequal(app.StateSelected, 'All')
                        Country = app.ListOfCountries{IndexOfCurrentCountryExcludingGlobal};
                        app.AverageDailyCases = movmean(Country.DailyCases, app.AverageOfDay);
                        app.AverageDailyDeaths = movmean(Country.DailyDeaths, app.AverageOfDay);
                        app.AverageCumulativeCases = movmean(Country.CumulativeCases, app.AverageOfDay);
                        app.AverageCumulativeDeaths = movmean(Country.CumulativeDeaths, app.AverageOfDay);
                    else
                        [~,index] = ismember(app.StateSelected, app.StateorRegionListBox.Items);
                        State = app.ListOfCountries{IndexOfCurrentCountryExcludingGlobal}.ListOfStates{index-1};
                        app.AverageDailyCases = movmean(State.DailyCases, app.AverageOfDay);
                        app.AverageDailyDeaths = movmean(State.DailyDeaths, app.AverageOfDay);
                        app.AverageCumulativeCases = movmean(State.CumulativeCases, app.AverageOfDay);
                        app.AverageCumulativeDeaths = movmean(State.CumulativeDeaths, app.AverageOfDay);
                    end
                end
            else
                if isequal(app.CountrySelected, 'Global')
                    app.AverageDailyCases = app.GlobalDailyCases;
                    app.AverageDailyDeaths = app.GlobalDailyDeaths;
                    app.AverageCumulativeCases = app.GlobalCumulativeCases;
                    app.AverageCumulativeDeaths = app.GlobalCumulativeDeaths;
                else
                    IndexOfCurrentCountryExcludingGlobal = findobj([app.ListOfCountries{:}], 'Name', app.CountrySelected).IndexOfCountryExcludingGlobal;
                    if isequal(app.StateSelected, 'All')
                        Country = app.ListOfCountries{IndexOfCurrentCountryExcludingGlobal};
                        app.AverageDailyCases = Country.DailyCases;
                        app.AverageDailyDeaths = Country.DailyDeaths;
                        app.AverageCumulativeCases = Country.CumulativeCases;
                        app.AverageCumulativeDeaths = Country.CumulativeDeaths;
                    else
                        [~,index] = ismember(app.StateSelected, app.StateorRegionListBox.Items);
                        State = app.ListOfCountries{IndexOfCurrentCountryExcludingGlobal}.ListOfStates{index-1};
                        app.AverageDailyCases = State.DailyCases;
                        app.AverageDailyDeaths = State.DailyDeaths;
                        app.AverageCumulativeCases = State.CumulativeCases;
                        app.AverageCumulativeDeaths = State.CumulativeDeaths;
                    end
                end
            end
        end
        function title = MakeTitle(app)
            if app.ShowCumulative
                ChartFrequency = 'Cumulative';
            elseif app.ShowDaily
                ChartFrequency = 'Daily';
            end
            
            if (app.ShowCases && ~app.ShowDeaths)
                ChartDataType = 'Cases';
            elseif (app.ShowDeaths && ~app.ShowCases)
                ChartDataType = 'Deaths';
            else
                ChartDataType = 'Cases and Deaths';
            end
            
            if isequal(app.CountrySelected, 'Global')
                ChartName = 'Globally';
            elseif isequal(app.StateSelected, 'All')
                ChartName = [ 'in ', app.CountrySelected ];
            else
                ChartName = [ 'in ', app.StateSelected ];
            end

            if (app.AverageOfDay > 1)
                ChartDaysMean = [ ' (', num2str(app.AverageOfDay), '-day mean)' ];
            else
                ChartDaysMean = '';
            end

            title = [ ChartFrequency, ' Number of ', ChartDataType, ' ', ChartName, ChartDaysMean ];               
        end
        function SetDefault(app)
            % Populate app properties
            app.UIFigure.Icon = fullfile(pwd, 'icons', 'APP_ico.png');
            
            app.SetUIFigureColorTool.Icon = fullfile(pwd, 'icons', 'UI_ico.png');
            app.SetUIFigureColorTool.Tooltip = 'Change UI Figure Color';
            
            app.SendEmailTool.Icon =  fullfile(pwd, 'icons', 'email-icon.png');
            app.SendEmailTool.Tooltip = 'For more details and any further questions, please kindly contact developer at farhaad.abedinzade@gmail.com ';


            app.OpenGitHubRepoTool.Icon = fullfile(pwd, 'icons', 'Git.png');
            app.OpenGitHubRepoTool.Tooltip = 'Open GitHub Repository for Source Code';
            
            app.EditField.FontColor = [ 0.65 0.65 0.65 ];
            app.EditField.BackgroundColor = 'white';
            app.EditField.Placeholder = 'Enter Country Name...';
            %app.EditField.FontWeight = 'bold';
            
            app.ClearButton.Icon = fullfile(pwd, 'icons', 'clear.png');
            app.ClearButton.Tooltip = 'Clear';
            
            app.CountryListBox.Items = [ {'Global'}; app.ListOfCountryNames ];
            app.CountryListBox.Value = 'Global';
            scroll(app.CountryListBox, 'top');
            app.StateorRegionListBox.Items = {'All'};
            app.StateorRegionListBox.Value = 'All';
            app.CumulativeButton.Value = true;
            app.CasesButton.Value = true;
            app.DailyButton.Value = false;
            app.DeathsButton.Value = false;
            app.BothButton.Value = false;
            app.AveragednumberofdaysSlider.Value = 1;
            
            app.StartDate = datenum(app.Dates{1}, 'mm/dd/yy');
            app.EndDate = datenum(app.Dates{end}, 'mm/dd/yy');
            app.FromDatePicker.Limits = [ datetime(app.StartDate, 'ConvertFrom', 'datenum'), ...
                                          datetime(app.EndDate, 'ConvertFrom', 'datenum') ];
            app.ToDatePicker.Limits = app.FromDatePicker.Limits;
            app.FromDatePicker.Value = datetime(app.StartDate, 'ConvertFrom', 'datenum');
            app.ToDatePicker.Value = datetime(app.EndDate, 'ConvertFrom', 'datenum');
            
            % Set my properties
            app.CountrySelected = app.CountryListBox.Value;
            app.StateSelected = app.StateorRegionListBox.Value;
            app.AverageOfDay = app.AveragednumberofdaysSlider.Value;
            app.ShowCases = app.CasesButton.Value || app.BothButton.Value;
            app.ShowDeaths = app.DeathsButton.Value || app.BothButton.Value;
            app.ShowBoth = app.BothButton.Value;
            app.ShowCumulative = app.CumulativeButton.Value;
            app.ShowDaily = app.DailyButton.Value;
            
            app.PlotData();
        end
        function LabelDates(app)
            app.Dates = app.GlobalData(1,3:end);
            app.StartDate = datenum(app.Dates{1}, 'mm/dd/yy');
            app.EndDate = datenum(app.Dates{end}, 'mm/dd/yy');
            app.TimePoints = linspace(app.StartDate , app.EndDate, length(app.Dates));
        end
        function GenerateData(app)
            app.ListOfCountryNames = unique(app.GlobalData(2:end,1));
            app.NumberOfCountries = length(app.ListOfCountryNames);
            app.NumberOfStates = app.NumberOfCountries - sum(cellfun('isempty', app.GlobalData(2:end,2)));
            keySet = cell(1,app.NumberOfStates);
            valueSet = cell(1,app.NumberOfStates);
            index = 1;
            for ii = 1 : app.NumberOfCountries
                nameOfCountry = app.ListOfCountryNames{ii};
                [~,indexOfCountryIncludingGlobal] = ismember(nameOfCountry, app.GlobalData(:,1));
                app.ListOfCountries{ii} = CovidCountryData(app.GlobalData, nameOfCountry, indexOfCountryIncludingGlobal);
                if ~isempty(app.ListOfCountries{ii}.ListOfStates)
                    for jj = 1 : length(app.ListOfCountries{ii}.ListOfStates)
                        keySet{index} = app.ListOfCountries{ii}.ListOfStates{jj}.Name;
                        valueSet{index} = app.ListOfCountries{ii}.Name;
                        index = index + 1;
                    end
                end
            end
            app.StateMap = containers.Map(keySet, valueSet);
            
            app.GlobalCumulativeCases = zeros(1,length(app.Dates));   % pre-allocation
            for i = 1 : app.NumberOfCountries
                app.GlobalCumulativeCases = app.GlobalCumulativeCases + app.ListOfCountries{i}.CumulativeCases;
            end
            app.GlobalCumulativeDeaths = zeros(1,length(app.Dates));
            for i = 1 : app.NumberOfCountries
                app.GlobalCumulativeDeaths = app.GlobalCumulativeDeaths + app.ListOfCountries{i}.CumulativeDeaths;
            end
            app.GlobalDailyCases = zeros(1,length(app.Dates));
            for i = 1 : app.NumberOfCountries
                app.GlobalDailyCases = app.GlobalDailyCases + app.ListOfCountries{i}.DailyCases;
            end
            app.GlobalDailyDeaths = zeros(1,length(app.Dates));
            for i = 1 : app.NumberOfCountries
                app.GlobalDailyDeaths = app.GlobalDailyDeaths + app.ListOfCountries{i}.DailyDeaths;
            end
        end
        function PlotData(app)
            cla(app.UIAxes, 'reset');
            app.ComputeAverages();
            if ((app.ShowCases && ~app.ShowDeaths) && app.ShowCumulative)
                bar(app.UIAxes, app.TimePoints, app.AverageCumulativeCases, 'FaceColor', '#0072BD');
                xlabel(app.UIAxes, 'Dates');
                ylabel(app.UIAxes, 'Number of Cases');
            elseif ((app.ShowCases && ~app.ShowDeaths) && app.ShowDaily)
                bar(app.UIAxes, app.TimePoints, app.AverageDailyCases, 'FaceColor', '#0072BD');
                ylabel(app.UIAxes, 'Number of Cases');
            elseif ((app.ShowDeaths && ~app.ShowCases) && app.ShowCumulative)
                plot(app.UIAxes, app.TimePoints, app.AverageCumulativeDeaths, 'Color', 'red', 'LineWidth', 1.5);
                ylabel(app.UIAxes, 'Number of Deaths');
                app.UIAxes.YTick = unique( round(app.UIAxes.YTick) );
            elseif ((app.ShowDeaths && ~app.ShowCases) && app.ShowDaily)
                plot(app.UIAxes, app.TimePoints, app.AverageDailyDeaths, 'Color', 'red', 'LineWidth', 1.5);
                ylabel(app.UIAxes, 'Number of Deaths');
                app.UIAxes.YTick = unique( round(app.UIAxes.YTick) );
            elseif (app.ShowBoth && app.ShowCumulative)
                % plot 1
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes, app.TimePoints, app.AverageCumulativeCases, 'FaceColor', '#0072BD');
                app.UIAxes.YColor = [ 0 0.4470 0.7410 ];
                ylabel(app.UIAxes, 'Number of Cases');
                ytl = get(app.UIAxes, 'YTick');                 % Get controlling left ticks
                
                hold(app.UIAxes, 'on');
                
                % plot 2
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes, app.TimePoints, app.AverageCumulativeDeaths, 'Color', 'red', 'LineWidth', 1.5);
                app.UIAxes.YColor = [ 0.8500 0.3250 0.0980 ];
                ylabel(app.UIAxes, 'Number of Deaths');
                
                if (max(app.AverageCumulativeDeaths) < 20)
                    app.UIAxes.YTick = unique( round(app.UIAxes.YTick) );
                    app.UIAxes.YLim(1) = 0;
                else
                    ytr = get(app.UIAxes, 'YTick');             % Get original right tick values
                    ytrv = linspace(0, max(ytr), numel(ytl));   % Create new right tick values matching # left ticks
                    app.UIAxes.YTick = ytrv;                    % Set new right ticks
                    app.UIAxes.YTickLabels = round(ytrv);       % Set new right tick labels
                end
                
                hold(app.UIAxes, 'off');
            elseif (app.ShowBoth && app.ShowDaily)
                % plot 1
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes, app.TimePoints, app.AverageDailyCases, 'FaceColor', '#0072BD');
                app.UIAxes.YColor = [ 0 0.4470 0.7410 ];
                ylabel(app.UIAxes, 'Number of Cases');
                ytl = get(app.UIAxes, 'YTick');                 % Get controlling left ticks
                
                hold(app.UIAxes, 'on');
                
                % plot 2
                yyaxis(app.UIAxes, 'right');
                plot(app.UIAxes, app.TimePoints, app.AverageDailyDeaths, 'Color', 'red', 'LineWidth', 1.5);
                app.UIAxes.YColor = [ 0.8500 0.3250 0.0980 ];
                ylabel(app.UIAxes, 'Number of Deaths');

                if (max(app.AverageDailyDeaths) < 20)
                    app.UIAxes.YTick = unique(round(app.UIAxes.YTick));
                    app.UIAxes.YLim(1) = 0;
                else
                    ytr = get(app.UIAxes, 'YTick');             % Get original right tick values
                    ytrv = linspace(0, max(ytr), numel(ytl));   % Create new right tick values matching # left ticks
                    app.UIAxes.YTick = ytrv;                    % Set new right ticks
                    app.UIAxes.YTickLabels = round(ytrv);       % Set new right tick labels
                end
                
                hold(app.UIAxes, 'off');
            end
            
            set(app.UIAxes, 'FontName', 'Segoe UI', 'FontSize', 14);
            grid(app.UIAxes, 'on');
            box(app.UIAxes, 'off');
            
            % format date timepoints on x axis
            datetick(app.UIAxes, 'x', 'mm/dd');
            xlim(app.UIAxes, 'manual');
            xlim(app.UIAxes, [ app.StartDate app.EndDate ]);
            %xtickangle(app.UIAxes, 30);
            xlabel(app.UIAxes, 'Dates');
            
            % turn off scientic notations on y axes
            if app.ShowBoth
                app.UIAxes.YAxis(1).Exponent = 0;
                app.UIAxes.YAxis(2).Exponent = 0;
            else
                app.UIAxes.YAxis.Exponent = 0;
            end
            ytickformat(app.UIAxes, '%.0f');
            
            title(app.UIAxes, app.MakeTitle(), 'FontSize', 18);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data;
            app.GlobalData = covid_data;
            app.LabelDates();
            app.GenerateData(); 
            app.SetDefault();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.CountrySelected = app.CountryListBox.Value;
            app.StateorRegionListBox.Items = [ {'All'}, ...
                                               findobj([app.ListOfCountries{:}], 'Name', app.CountrySelected).ListOfStateNames ];
            app.StateSelected = 'All';
            app.PlotData();
        end

        % Callback function
        function StateorRegionListBoxValueChanged(app, event)
            app.StateSelected = app.StateorRegionListBox.Value;
            app.PlotData();
        end

        % Selection changed function: DatatoplotButtonGroup
        function DatatoplotButtonGroupSelectionChanged(app, event)
            app.ShowCases = app.CasesButton.Value || app.BothButton.Value;
            app.ShowDeaths = app.DeathsButton.Value || app.BothButton.Value;
            app.ShowBoth = app.BothButton.Value;
            app.PlotData();
        end

        % Selection changed function: OptionsButtonGroup
        function OptionsButtonGroupSelectionChanged(app, event)
            app.ShowCumulative = app.CumulativeButton.Value;
            app.ShowDaily = app.DailyButton.Value;
            app.PlotData();
        end

        % Value changed function: AveragednumberofdaysSlider
        function AveragednumberofdaysSliderValueChanged(app, event)
            % determine which discrete option the current value is closest to
            [~,minIdx] = min(abs(app.AveragednumberofdaysSlider.Value - app.AveragednumberofdaysSlider.MajorTicks(:)));
            % move the slider to that option
            app.AveragednumberofdaysSlider.Value = app.AveragednumberofdaysSlider.MajorTicks(minIdx);
            % override the selected value if you plan on using it within this function
            app.AverageOfDay = app.AveragednumberofdaysSlider.MajorTicks(minIdx);
            % plot data
            app.PlotData();
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            app.CountryorStateorRegionToFind = event.Value;
            if (app.CountryorStateorRegionToFind == "")
                app.SetDefault();
            else
                Countries = [ {'Global'}; app.ListOfCountryNames ];
                CountryMatched = startsWith(lower(string(Countries)), lower(app.CountryorStateorRegionToFind));
                if (sum(CountryMatched) == 0)
                    StateorRegionMatched = isKey(app.StateMap, app.CountryorStateorRegionToFind);
                    if (~StateorRegionMatched)
                        app.EditField.BackgroundColor = 'red';
                        app.EditField.FontColor = 'black';
                        app.CountryListBox.Items = "No country found.";
                        app.StateorRegionListBox.Items = "";
                        cla(app.UIAxes, 'reset');
                    else
                        app.EditField.BackgroundColor = 'yellow';
                        app.EditField.FontColor = '#05014a';
                        app.CountryListBox.Items = {app.StateMap(app.CountryorStateorRegionToFind)};
                        app.CountrySelected = app.StateMap(app.CountryorStateorRegionToFind);
                        app.StateorRegionListBox.Items = [ {'All'}, ...
                                                           findobj([app.ListOfCountries{:}], 'Name', app.CountrySelected).ListOfStateNames ];
                        app.StateSelected = app.CountryorStateorRegionToFind;
                        app.StateorRegionListBox.Value = app.StateSelected;
                        scroll(app.StateorRegionListBox, app.StateorRegionListBox.Value);
                        app.PlotData();
                    end
                else
                    app.EditField.BackgroundColor = 'yellow';
                    app.EditField.FontColor = '#05014a';
                    FilteredValues = Countries(CountryMatched);
                    app.CountryListBox.Items = FilteredValues;
                    app.CountrySelected = app.CountryListBox.Items{1};
                    app.StateorRegionListBox.Items = [ {'All'}, ...
                                                       findobj([app.ListOfCountries{:}], 'Name', app.CountrySelected).ListOfStateNames ];
                    app.StateSelected = 'All';
                    app.PlotData();
                end
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.EditField.Value = '';
            app.SetDefault();
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
             app.SetDefault();
        end

        % Value changed function: FromDatePicker
        function FromDatePickerValueChanged(app, event)
            app.StartDate = datenum(app.FromDatePicker.Value);
            if (app.StartDate >= app.EndDate)
                app.EndDate = min([ app.StartDate + 1, datenum(app.ToDatePicker.Limits(2)) ]);
                app.ToDatePicker.Value = datetime(app.EndDate, 'ConvertFrom', 'datenum');
                app.StartDate = app.EndDate - 1;
                app.FromDatePicker.Value = datetime(app.StartDate, 'ConvertFrom', 'datenum');
            end
            app.PlotData();
        end

        % Value changed function: StateorRegionListBox
        function ToDatePickerValueChanged(app, event)
            app.EndDate = datenum(app.ToDatePicker.Value);
            if (app.EndDate <= app.StartDate)
                app.StartDate = max([ app.EndDate - 1, datenum(app.FromDatePicker.Limits(1)) ]);
                app.FromDatePicker.Value = datetime(app.StartDate, 'ConvertFrom', 'datenum');
                app.EndDate = app.StartDate + 1;
                app.ToDatePicker.Value = datetime(app.EndDate, 'ConvertFrom', 'datenum');
            end
            app.PlotData();
        end

        % Callback function: SetUIFigureColorTool, ToDatePicker
        function SetUIFigureColorToolClicked(app, event)
            uisetcolor(app.UIFigure, 'Select UI Figure Color');
        end

        % Clicked callback: SendEmailTool
        function SendEmailToolClicked(app, event)
            email = 'farhaad.abedinzade@gmail.com';
            url = [ 'mailto:', email ];
            web(url);
            
        end

        % Clicked callback: OpenGitHubRepoTool
        function OpenGitHubRepoToolClicked(app, event)
            url = 'https://github.com/farhadabedinzadeh/FinalProject_Covid19-Data-Visualization';
            web(url);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Covid 19 Data Statistical Visualization';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.SetUIFigureColorTool = uipushtool(app.Toolbar);
            app.SetUIFigureColorTool.ClickedCallback = createCallbackFcn(app, @SetUIFigureColorToolClicked, true);
            app.SetUIFigureColorTool.Icon = fullfile(pathToMLAPP, 'icons', 'ui.png');
            app.SendEmailTool = uipushtool(app.Toolbar);
            app.SendEmailTool.ClickedCallback = createCallbackFcn(app, @SendEmailToolClicked, true);
            app.SendEmailTool.Icon = fullfile(pathToMLAPP, 'icons', 'email-icon.png');
            app.OpenGitHubRepoTool = uipushtool(app.Toolbar);
            app.OpenGitHubRepoTool.ClickedCallback = createCallbackFcn(app, @OpenGitHubRepoToolClicked, true);
            app.OpenGitHubRepoTool.Icon = fullfile(pathToMLAPP, 'icons', 'git.png');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Icon = fullfile(pathToMLAPP, 'icons', 'clear.png');
            app.ClearButton.Text = 'Clear';
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Icon = fullfile(pathToMLAPP, 'icons', 'default_icon.png');
            app.DefaultButton.Text = 'Default';
            app.FromDatePickerLabel = uilabel(app.UIFigure);
            app.FromDatePickerLabel.Text = 'From';
            app.FromDatePicker = uidatepicker(app.UIFigure);
            app.FromDatePicker.ValueChangedFcn = createCallbackFcn(app, @FromDatePickerValueChanged, true);
            app.ToDatePickerLabel = uilabel(app.UIFigure);
            app.ToDatePickerLabel.Text = 'To';
            app.ToDatePicker = uidatepicker(app.UIFigure);
            app.ToDatePicker.ValueChangedFcn = createCallbackFcn(app, @SetUIFigureColorToolClicked, true);
            app.CountryLabel = uilabel(app.UIFigure);
            app.CountryLabel.Text = 'Country';
            app.StateorRegionLabel = uilabel(app.UIFigure);
            app.StateorRegionLabel.Text = 'State or Region';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @ToDatePickerValueChanged, true);
            app.AveragednumberofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragednumberofdaysSliderLabel.Text = 'Averaged number of days';
            app.AveragednumberofdaysSlider = uislider(app.UIFigure);
            app.AveragednumberofdaysSlider.Limits = [1 15];
            app.AveragednumberofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragednumberofdaysSliderValueChanged, true);
            app.AveragednumberofdaysSlider.MinorTicks = [];
            app.AveragednumberofdaysSlider.Value = 1;
            app.DatatoplotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoplotButtonGroupSelectionChanged, true);
            app.DatatoplotButtonGroup.Title = 'Data to plot';
            app.CasesButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionsButtonGroupSelectionChanged, true);
            app.OptionsButtonGroup.Title = 'Options';
            app.CumulativeButton = uiradiobutton(app.OptionsButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionsButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://coronavirus.jhu.edu/';
            app.Hyperlink.Text = 'Data source: Coronavirus Resource Center at the Johns Hopkins University';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'hopkins_logo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covid19StatisticsProgram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end